classdef mygui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BackButton                   matlab.ui.control.StateButton
        StatusButton                 matlab.ui.control.StateButton
        TrunkSwitch                  matlab.ui.control.Switch
        TrunkSwitchLabel             matlab.ui.control.Label
        HoodSwitch                   matlab.ui.control.Switch
        HoodLabel                    matlab.ui.control.Label
        DoorleftSwitch               matlab.ui.control.Switch
        DoorleftSwitchLabel          matlab.ui.control.Label
        DoorrightSwitch              matlab.ui.control.Switch
        DoorrightSwitchLabel         matlab.ui.control.Label
        trunk_unlock_icon            matlab.ui.control.Image
        trunk_lock_icon              matlab.ui.control.Image
        hood_lock_icon               matlab.ui.control.Image
        hood_unlock_icon             matlab.ui.control.Image
        left_side_door_unlock_icon   matlab.ui.control.Image
        left_side_door_lock_icon     matlab.ui.control.Image
        car_top_view                 matlab.ui.control.Image
        right_side_door_lock_icon    matlab.ui.control.Image
        right_side_door_unlock_icon  matlab.ui.control.Image
        TextArea                     matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: right_side_door_lock_icon
        function right_side_door_lock_iconClicked(app, event)
            value = app.DoorrightfrontCheckBox.Value;
            if value == 1
                msgbox("Right side door locked");
            else
                msgbox("Right side door unlocked");
            end
        end

        % Callback function
        function DoorrightfrontCheckBoxValueChanged(app, event)

        end

        % Image clicked function: right_side_door_unlock_icon
        function right_side_door_unlock_iconImageClicked(app, event)
            value = app.DoorrightfrontCheckBox.Value;
            if value == 1
                msgbox("Right door locked");
            else
                msgbox("Right door unlocked");
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            app.TextArea.Visible = 0;

        end

        % Callback function
        function DoorleftfrontCheckBoxValueChanged(app, event)

        end

        % Callback function
        function HoodCheckBoxValueChanged(app, event)

        end

        % Callback function
        function TrunkCheckBoxValueChanged(app, event)

        end

        % Callback function
        function SwitchValueChanged(app, event)

        end

        % Value changed function: DoorrightSwitch
        function DoorrightSwitchValueChanged(app, event)
            value = app.DoorrightSwitch.Value;
            if value == "Lock"
                app.right_side_door_lock_icon.Visible = 1;
                app.right_side_door_unlock_icon.Visible = 0;
                app.TextArea.Value = "Right Door Locked";
                app.TextArea.Visible = 1;
            else
                app.right_side_door_lock_icon.Visible = 0;
                app.right_side_door_unlock_icon.Visible = 1;
                app.TextArea.Value = "Right Door Unlocked";
                app.TextArea.Visible = 1;
            end
        end

        % Value changed function: DoorleftSwitch
        function DoorleftSwitchValueChanged(app, event)
            value = app.DoorleftSwitch.Value;
            if value == "Lock"
                app.left_side_door_lock_icon.Visible = 1;
                app.left_side_door_unlock_icon.Visible = 0;
                app.TextArea.Value = "Left Door Locked";
                app.TextArea.Visible = 1;
            else
                app.left_side_door_lock_icon.Visible = 0;
                app.left_side_door_unlock_icon.Visible = 1;
                app.TextArea.Value = "Left Door Unlocked";
                app.TextArea.Visible = 1;
            end
        end

        % Value changed function: HoodSwitch
        function HoodSwitchValueChanged(app, event)
            value = app.HoodSwitch.Value;
            if value == "Lock"
                app.hood_lock_icon.Visible = 1;
                app.hood_unlock_icon.Visible = 0;
                app.TextArea.Value = "Hood Locked";
                app.TextArea.Visible = 1;
            else
                app.hood_lock_icon.Visible = 0;
                app.hood_unlock_icon.Visible = 1;
                app.TextArea.Value = "Hood Unlocked";
                app.TextArea.Visible = 1;
            end
        end

        % Value changed function: TrunkSwitch
        function TrunkSwitchValueChanged(app, event)
            value = app.TrunkSwitch.Value;
            if value == "Lock"
                app.trunk_lock_icon.Visible = 1;
                app.trunk_unlock_icon.Visible = 0;
                app.TextArea.Value = "Trunk Locked";
                app.TextArea.Visible = 1;
            else
                app.trunk_lock_icon.Visible = 0;
                app.trunk_unlock_icon.Visible = 1;
                app.TextArea.Value = "Trunk Unlocked";
                app.TextArea.Visible = 1;
            end
        end

        % Value changed function: StatusButton
        function StatusButtonValueChanged(app, event)
            leftdoorstatus = app.DoorleftSwitch.Value;
            rightdoorstatus = app.DoorrightSwitch.Value;
            hoodstatus = app.HoodSwitch.Value;
            trunkstatus = app.TrunkSwitch.Value;
            if ((leftdoorstatus == "Unlock") || (rightdoorstatus=="Unlock") || (hoodstatus == "Unlock") || (trunkstatus=="Unlock"))
                app.TextArea.Value = "Doors are not locked";
            else
                app.TextArea.Value = "All Doors are locked";
            end
            app.TextArea.Visible=1;
            value = app.StatusButton.Value;
            app.BackButton.Visible = 1;
            if value == 1
                app.DoorleftSwitch.Visible = 1;
                app.DoorleftSwitchLabel.Visible = 1;
                app.DoorrightSwitch.Visible = 1;
                app.DoorrightSwitchLabel.Visible =1;
                app.HoodSwitch.Visible = 1;
                app.HoodLabel.Visible = 1;
                app.TrunkSwitch.Visible = 1;
                app.TrunkSwitchLabel.Visible = 1;
                if rightdoorstatus == "Unlock"
                    app.right_side_door_unlock_icon.Visible = 1;
                end
                if leftdoorstatus == "Unlock"
                    app.left_side_door_unlock_icon.Visible=1;
                end
                if hoodstatus == "Unlock"
                    app.hood_unlock_icon.Visible=1;
                end
                if trunkstatus == "Unlock"
                    app.trunk_unlock_icon.Visible =1;
                end
            else
                app.DoorleftSwitch.Visible = 0;
                app.DoorleftSwitchLabel.Visible = 0;
                app.DoorrightSwitch.Visible = 0;
                app.DoorrightSwitchLabel.Visible =0;
                app.HoodSwitch.Visible = 0;
                app.HoodLabel.Visible = 0;
                app.TrunkSwitch.Visible = 0;
                app.TrunkSwitchLabel.Visible = 0;
            end
            app.StatusButton.Visible = 0;
            app.StatusButton.Value = 0;
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
            value = app.BackButton.Value;
            if value == 1
                app.DoorleftSwitch.Visible = 0;
                app.DoorleftSwitchLabel.Visible = 0;
                app.DoorrightSwitch.Visible = 0;
                app.DoorrightSwitchLabel.Visible =0;
                app.HoodSwitch.Visible = 0;
                app.HoodLabel.Visible = 0;
                app.TrunkSwitch.Visible = 0;
                app.TrunkSwitchLabel.Visible = 0;
            end
            app.BackButton.Visible = 0;
            app.BackButton.Value = 0;
            app.StatusButton.Visible = 1;
            app.TextArea.Visible=0;
            
            app.right_side_door_lock_icon.Visible=0;
            app.left_side_door_lock_icon.Visible=0;
            app.hood_lock_icon.Visible = 0;
            app.trunk_lock_icon.Visible =0;
            
            app.right_side_door_unlock_icon.Visible=0;
            app.left_side_door_unlock_icon.Visible=0;
            app.hood_unlock_icon.Visible = 0;
            app.trunk_unlock_icon.Visible =0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Visible = 'off';
            app.right_side_door_unlock_icon = uiimage(app.UIFigure);
            app.right_side_door_unlock_icon.ImageClickedFcn = createCallbackFcn(app, @right_side_door_unlock_iconImageClicked, true);
            app.right_side_door_unlock_icon.Visible = 'off';
            app.right_side_door_unlock_icon.ImageSource = 'unlock_icon.png';
            app.right_side_door_lock_icon = uiimage(app.UIFigure);
            app.right_side_door_lock_icon.ImageClickedFcn = createCallbackFcn(app, @right_side_door_lock_iconClicked, true);
            app.right_side_door_lock_icon.Visible = 'off';
            app.right_side_door_lock_icon.ImageSource = 'lock_icon.png';
            app.car_top_view = uiimage(app.UIFigure);
            app.car_top_view.ImageSource = 'car-topview.png';
            app.left_side_door_lock_icon = uiimage(app.UIFigure);
            app.left_side_door_lock_icon.Visible = 'off';
            app.left_side_door_lock_icon.ImageSource = 'lock_icon.png';
            app.left_side_door_unlock_icon = uiimage(app.UIFigure);
            app.left_side_door_unlock_icon.Visible = 'off';
            app.left_side_door_unlock_icon.ImageSource = 'unlock_icon.png';
            app.hood_unlock_icon = uiimage(app.UIFigure);
            app.hood_unlock_icon.Visible = 'off';
            app.hood_unlock_icon.ImageSource = 'unlock_icon.png';
            app.hood_lock_icon = uiimage(app.UIFigure);
            app.hood_lock_icon.Visible = 'off';
            app.hood_lock_icon.ImageSource = 'lock_icon.png';
            app.trunk_lock_icon = uiimage(app.UIFigure);
            app.trunk_lock_icon.Visible = 'off';
            app.trunk_lock_icon.ImageSource = 'lock_icon.png';
            app.trunk_unlock_icon = uiimage(app.UIFigure);
            app.trunk_unlock_icon.Visible = 'off';
            app.trunk_unlock_icon.ImageSource = 'unlock_icon.png';
            app.DoorrightSwitchLabel = uilabel(app.UIFigure);
            app.DoorrightSwitchLabel.Visible = 'off';
            app.DoorrightSwitchLabel.Text = 'Door-right';
            app.DoorrightSwitch = uiswitch(app.UIFigure, 'slider');
            app.DoorrightSwitch.Items = {'Unlock', 'Lock'};
            app.DoorrightSwitch.ValueChangedFcn = createCallbackFcn(app, @DoorrightSwitchValueChanged, true);
            app.DoorrightSwitch.Visible = 'off';
            app.DoorrightSwitch.Value = 'Unlock';
            app.DoorleftSwitchLabel = uilabel(app.UIFigure);
            app.DoorleftSwitchLabel.Visible = 'off';
            app.DoorleftSwitchLabel.Text = 'Door-left';
            app.DoorleftSwitch = uiswitch(app.UIFigure, 'slider');
            app.DoorleftSwitch.Items = {'Unlock', 'Lock'};
            app.DoorleftSwitch.ValueChangedFcn = createCallbackFcn(app, @DoorleftSwitchValueChanged, true);
            app.DoorleftSwitch.Visible = 'off';
            app.DoorleftSwitch.Value = 'Unlock';
            app.HoodLabel = uilabel(app.UIFigure);
            app.HoodLabel.Visible = 'off';
            app.HoodLabel.Text = 'Hood    ';
            app.HoodSwitch = uiswitch(app.UIFigure, 'slider');
            app.HoodSwitch.Items = {'Unlock', 'Lock'};
            app.HoodSwitch.ValueChangedFcn = createCallbackFcn(app, @HoodSwitchValueChanged, true);
            app.HoodSwitch.Visible = 'off';
            app.HoodSwitch.Value = 'Unlock';
            app.TrunkSwitchLabel = uilabel(app.UIFigure);
            app.TrunkSwitchLabel.Visible = 'off';
            app.TrunkSwitchLabel.Text = 'Trunk';
            app.TrunkSwitch = uiswitch(app.UIFigure, 'slider');
            app.TrunkSwitch.Items = {'Unlock', 'Lock'};
            app.TrunkSwitch.ValueChangedFcn = createCallbackFcn(app, @TrunkSwitchValueChanged, true);
            app.TrunkSwitch.Visible = 'off';
            app.TrunkSwitch.Value = 'Unlock';
            app.StatusButton = uibutton(app.UIFigure, 'state');
            app.StatusButton.ValueChangedFcn = createCallbackFcn(app, @StatusButtonValueChanged, true);
            app.StatusButton.Text = 'Status';
            app.BackButton = uibutton(app.UIFigure, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Visible = 'off';
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mygui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end